classdef DIP_PROJ_finalSUB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TextArea_2                 matlab.ui.control.TextArea
        EditField_2                matlab.ui.control.NumericEditField
        Specifyfiltersize321Label  matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        Image_2                    matlab.ui.control.Image
        ExitButton                 matlab.ui.control.Button
        OptionDropDown             matlab.ui.control.DropDown
        ApplyimageprocessingtechniqueLabel  matlab.ui.control.Label
        TechniqueDropDown          matlab.ui.control.DropDown
        AdjustThresholdKnob        matlab.ui.control.Knob
        AdjustThresholdKnobLabel   matlab.ui.control.Label
        DownloadImageButton        matlab.ui.control.Button
        TextArea                   matlab.ui.control.TextArea
        SelectImageButton          matlab.ui.control.Button
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        img_src % Description
        img
        
        img2
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            %global img_src;
            [file, path] = uigetfile('*.*', 'Select an Image');
            file=strcat(path,file);
            app.img_src=imread(file);
            imshow(app.img_src,'Parent',app.UIAxes);
            app.img2=app.img_src;
            app.img_src=im2gray(app.img_src);
            imshow(im2gray(app.img_src),'Parent',app.UIAxes_2);
    
        end

        % Clicked callback: TechniqueDropDown
        function TechniqueDropDownClicked(app, event)

             value = app.TechniqueDropDown.Value;
            switch value
                %case "Apply Filter"
                %    app.OptionDropDown.Items = {'Butterworth', '', ''};
                case "Change color scheme"
                    app.OptionDropDown.Items = {'HSV','YIQ','Grayscale'};
                case "Noise Addition"
                    app.OptionDropDown.Items = {'Salt & Pepper', 'Speckle', 'Gaussian'};
                case "Noise Removal"
                    app.OptionDropDown.Items = {'Average Filtering', 'Median Filtering'};
                case "Edge Detection"
                    app.OptionDropDown.Items = {'Canny', 'Prewitt', 'Sobel','Roberts'};  
                case "Morphological operation"
                    app.OptionDropDown.Items = {'Dilation', 'Erosion'};   
                case "Other operations"
                    app.OptionDropDown.Items = {'Fourier Transform', 'Sharpen'}; 
            end
        end

        % Value changed function: TechniqueDropDown
        function TechniqueDropDownChanged(app, event)
           
             value = app.TechniqueDropDown.Value;
            switch value
                %case "Apply Filter"
                %    app.OptionDropDown.Items = {'Butterworth', '', ''};
                %case "Image enhancement"
                    %app.OptionDropDown.Items = {'Sharpening', '', ''};
                case "Change color scheme"
                    app.OptionDropDown.Items = {'HSV','YIQ','Grayscale'};
                case "Noise Addition"
                    app.OptionDropDown.Items = {'Salt & Pepper', 'Speckle', 'Gaussian'};
                case "Noise Removal"
                    app.OptionDropDown.Items = {'Average Filtering', 'Median Filtering'};
                case "Edge Detection"
                    app.OptionDropDown.Items = {'Canny', 'Prewitt', 'Sobel','Roberts'};  
                case "Morphological operation"
                    app.OptionDropDown.Items = {'Dilation', 'Erosion'}; 
                case "Other operations"
                    app.OptionDropDown.Items = {'Fourier Transform', 'Sharpen'}; 

            end
        end

        % Clicked callback: OptionDropDown
        function OptionDropDownClicked(app, event)
          
           
            value = app.OptionDropDown.Value;
            %app.img=im2gray(app.img);
            %imshow(im2gray(app.img_src),'Parent',app.UIAxes_2);
            switch value

                case "Salt & Pepper"
                    app.img=imnoise(app.img_src,'salt & pepper',0.02);
                    %app.img=imnoise(app.img,'salt & pepper',0.02);
                    imshow(app.img,'Parent',app.UIAxes_2);
                case "Speckle"
                    app.img=imnoise(app.img_src,'speckle',0.02);
                    imshow(app.img,'Parent',app.UIAxes_2);
                case "Gaussian"
                    app.img=imnoise(app.img_src,'gaussian',0.02);
                    imshow(app.img,'Parent',app.UIAxes_2);
                case "Median Filtering"
%                     app.img=imnoise(app.img,'salt & pepper',0.02);
%                     %app.img2=medfilt2(rgb2gray(app.img));
%                     app.img2=medfilt3(app.img);
%                     imshow(app.img2,'Parent',app.UIAxes_3);
                    f=medfilt3(app.img);
                    imshow(f,'Parent',app.UIAxes_3);

                    %app.img=medfilt3(app.img);
                    %imshow(app.img,'Parent',app.UIAxes_3);

                 case "Average Filtering"
%                     %% NOT WORKING
                      %% NOT WORKING
                      %%imfilter instead of filter 2
                    ff=fspecial('average');
%                     f=filter2(ff,app.img,'valid');
                    f=imfilter(app.img,ff);
                    imshow(f,'Parent',app.UIAxes_3);
                case "Canny"
                    if ndims(app.img) == 3
                        app.img=edge(rgb2gray(app.img),'Canny');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    else
                        app.img=edge(app.img_src,'Canny');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    end

                case "Prewitt"
                    if ndims(app.img) == 3
                        app.img=edge(rgb2gray(app.img),'prewitt');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    else
                        app.img=edge(app.img_src,'prewitt');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    end
                case "Sobel"
                    if ndims(app.img) == 3
                        app.img=edge(rgb2gray(app.img),'sobel');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    else
                        app.img=edge(app.img_src,'sobel');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    end
                case "Roberts"
                    if ndims(app.img) == 3
                        app.img=edge(rgb2gray(app.img),'roberts');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    else
                        app.img=edge(app.img_src,'roberts');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    end
                case "Dilation"
                    if islogical(app.img)
                        sel=ones(3);
                        app.img=imdilate(app.img_src,sel);

                    else
                        app.img=imbinarize(app.img);
                        sel=ones(3);
                        app.img=imdilate(app.img_src,sel);
                        
                    end
                    imshow(app.img,'Parent',app.UIAxes_3);
                case "Erosion"
                    if islogical(app.img)
                        sel=ones(3);
                        app.img=imerode(app.img_src,sel);

                    else
                        app.img=imbinarize(app.img);
                        sel=ones(3);
                        app.img=imerode(app.img_src,sel);
                        
                    end
                    imshow(app.img,'Parent',app.UIAxes_3);


                 case 'HSV'
                      
                    if (ndims(app.img2) == 3) && (size(app.img2, 3) == 3)
                        app.img=rgb2hsv(app.img2);
                        imshow(app.img,'Parent',app.UIAxes_3);
                    else 
                        app.img=app.img2;
                         imshow(app.img,'Parent',app.UIAxes_3);
                    end
                 case 'YIQ'
                     if (ndims(app.img2) == 3) && (size(app.img2, 3) == 3)
                        app.img=rgb2ntsc(app.img2);
               
                        imshow(app.img,'Parent',app.UIAxes_3);
                    else 
                        app.img=app.img2;
                         imshow(app.img,'Parent',app.UIAxes_3);

                     end

                case 'Grayscale'
                     if (ndims(app.img2) == 3) && (size(app.img2, 3) == 3)
                        app.img=rgb2gray(app.img2);
               
                        imshow(app.img,'Parent',app.UIAxes_3);
                    else 
                        app.img=app.img2;
                         imshow(app.img,'Parent',app.UIAxes_3);

                     end

                case 'Fourier Transform'
                    app.img=fftshift(fft2(app.img_src));
                    imshow(app.img,'Parent',app.UIAxes_3);

                case 'Sharpen'
                    f2=fspecial('unsharp');   
                    app.img=imfilter(app.img_src,f2);
                    imshow(app.img,'Parent',app.UIAxes_3);


%                case "Butterworth"
%                    app.img=rgb2gray(app.img);
%                    [x,y]= meshgrid(-128:127, -128:127);
%                    b1= 1./1+((x.^2+y.^2)/15).^2;
%                    cl=lbutter(b1,5,1);
%                    cf=fftshift(fft2(app.img));
%                    cfc1=cf.*b1;
%                    %fftshow(cfc1,'log');
%                    imshow(cfc1,'Parent',app.UIAxes_2);
%                    cfc1=cf.*cl;
%                    cfcli=ifft2(cfc1);
                    %fftshow(cfcli,'log');
%                    imshow(cfcli,'Parent',app.UIAxes_3);

            end
            
            
        end

        % Value changed function: OptionDropDown
        function OptionDropDownValueChanged(app, event)
 
            value = app.OptionDropDown.Value;
            %app.img=im2gray(app.img_src);
            %imshow(im2gray(app.img_src),'Parent',app.UIAxes_2);
            switch value

                case "Salt & Pepper"
                    app.img=imnoise(app.img,'salt & pepper',0.02);
                    %app.img=imnoise(app.img,'salt & pepper',0.02);
                    imshow(app.img,'Parent',app.UIAxes_2);
                case "Speckle"
                    app.img=imnoise(app.img,'speckle',0.02);
                    imshow(app.img,'Parent',app.UIAxes_2);
                case "Gaussian"
                    app.img=imnoise(app.img,'gaussian',0.02);
                    imshow(app.img,'Parent',app.UIAxes_2);
                case "Median Filtering"
%                     app.img=imnoise(app.img,'salt & pepper',0.02);
%                     %app.img2=medfilt2(rgb2gray(app.img));
%                     app.img2=medfilt3(app.img);
%                     imshow(app.img2,'Parent',app.UIAxes_3);
                    f=medfilt3(app.img);
                    imshow(f,'Parent',app.UIAxes_3);

                    %app.img=medfilt3(app.img);
                    %imshow(app.img,'Parent',app.UIAxes_3);

                 case "Average Filtering"
%                     %% NOT WORKING
                      %% NOT WORKING
                      %%imfilter instead of filter 2
                    ff=fspecial('average');
%                     f=filter2(ff,app.img,'valid');
                    f=imfilter(app.img,ff);
                    imshow(f,'Parent',app.UIAxes_3);
                case "Canny"
                    if ndims(app.img) == 3
                        app.img=edge(rgb2gray(app.img),'Canny');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    else
                        app.img=edge(app.img_src,'Canny');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    end

                case "Prewitt"
                    if ndims(app.img) == 3
                        app.img=edge(rgb2gray(app.img),'prewitt');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    else
                        app.img=edge(app.img_src,'prewitt');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    end
                case "Sobel"
                    if ndims(app.img) == 3
                        app.img=edge(rgb2gray(app.img),'sobel');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    else
                        app.img=edge(app.img_src,'sobel');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    end
                case "Roberts"
                    if ndims(app.img) == 3
                        app.img=edge(rgb2gray(app.img),'roberts');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    else
                        app.img=edge(app.img_src,'roberts');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    end
                case "Dilation"
                    if islogical(app.img)
                        sel=ones(3);
                        app.img=imdilate(app.img_src,sel);

                    else
                        app.img=imbinarize(app.img);
                        sel=ones(3);
                        app.img=imdilate(app.img_src,sel);
                        
                    end
                    imshow(app.img,'Parent',app.UIAxes_3);
                case "Erosion"
                    if islogical(app.img)
                        sel=ones(3);
                        app.img=imerode(app.img_src,sel);

                    else
                        app.img=imbinarize(app.img);
                        sel=ones(3);
                        app.img=imerode(app.img_src,sel);
                        
                    end
                    imshow(app.img,'Parent',app.UIAxes_3);

                             case 'HSV'
                      
                    if (ndims(app.img2) == 3) && (size(app.img2, 3) == 3)
                        app.img=rgb2hsv(app.img2);
                        imshow(app.img,'Parent',app.UIAxes_3);
                    else 
                        app.img=app.img2;
                         imshow(app.img,'Parent',app.UIAxes_3);
                    end
                 case 'YIQ'
                     if (ndims(app.img2) == 3) && (size(app.img2, 3) == 3)
                        app.img=rgb2ntsc(app.img2);
               
                        imshow(app.img,'Parent',app.UIAxes_3);
                    else 
                        app.img=app.img2;
                         imshow(app.img,'Parent',app.UIAxes_3);

                     end

                case 'Grayscale'
                     if (ndims(app.img2) == 3) && (size(app.img2, 3) == 3)
                        app.img=rgb2gray(app.img2);
               
                        imshow(app.img,'Parent',app.UIAxes_3);
                    else 
                        app.img=app.img2;
                         imshow(app.img,'Parent',app.UIAxes_3);

                     end
                 
               
                case 'Fourier Transform'
                    app.img=fftshift(fft2(app.img_src));
                    imshow(app.img,'Parent',app.UIAxes_3);

                case 'Sharpen'
                    f2=fspecial('unsharp');   
                    app.img=imfilter(app.img_src,f2);
                    imshow(app.img,'Parent',app.UIAxes_3);


%                case "Butterworth"
%                    app.img=rgb2gray(app.img);
%                    [x,y]= meshgrid(-128:127, -128:127);
%                    b1= 1./1+((x.^2+y.^2)/15).^2;
%                    cl=lbutter(b1,5,1);
%                    cf=fftshift(fft2(app.img));
%                    cfc1=cf.*b1;
%                    %fftshow(cfc1,'log');
%                    imshow(cfc1,'Parent',app.UIAxes_2);
%                    cfc1=cf.*cl;
%                    cfcli=ifft2(cfc1);
                    %fftshow(cfcli,'log');
%                    imshow(cfcli,'Parent',app.UIAxes_3);

            end


        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            %value = app.EditField_2.Value;
              changingValue = app.EditField_2.Value;
            if changingValue>=3 && changingValue<=21
    
                value = app.OptionDropDown.Value;
                switch value
                  
                    case "Salt & Pepper"
                        app.img=imnoise(app.img,'salt & pepper',0.02);
                        %app.img=imnoise(app.img,'salt & pepper',0.02);
                        imshow(app.img,'Parent',app.UIAxes_2);
                    case "Speckle"
                        app.img=imnoise(app.img,'speckle',0.02);
                        imshow(app.img,'Parent',app.UIAxes_2);
                    case "Gaussian"
                        app.img=imnoise(app.img,'gaussian',0.02);
                        imshow(app.img,'Parent',app.UIAxes_2);
    
                    case "Median Filtering"
    %                     app.img=imnoise(app.img,'salt & pepper',0.02);
    %                     %app.img2=medfilt2(rgb2gray(app.img));
    %                     app.img2=medfilt3(app.img);
    %                     imshow(app.img2,'Parent',app.UIAxes_3);
                     
                        f=medfilt3(app.img,  [changingValue,changingValue,changingValue]);
                        imshow(f,'Parent',app.UIAxes_3);
    
                    case "Average Filtering"
    %                     %% NOT WORKING
                          %% NOT WORKING
                          %%imfilter instead of filter 2
                        ff=fspecial('average',  changingValue);
    %                     f=filter2(ff,app.img,'valid');
                        f=imfilter(app.img,ff);
                        imshow(f,'Parent',app.UIAxes_3);
                    case "Canny"
                        if ndims(app.img) == 3
                            app.img=edge(rgb2gray(app.img),'Canny');
                            imshow(app.img,'Parent',app.UIAxes_3);
                        else
                            app.img=edge(app.img_src,'Canny');
                            imshow(app.img,'Parent',app.UIAxes_3);
                        end
    
                    case "Prewitt"
                        if ndims(app.img) == 3
                            app.img=edge(rgb2gray(app.img),'prewitt');
                            imshow(app.img,'Parent',app.UIAxes_3);
                        else
                            app.img=edge(app.img_src,'prewitt');
                            imshow(app.img,'Parent',app.UIAxes_3);
                        end
                    case "Sobel"
                        if ndims(app.img) == 3
                            app.img=edge(rgb2gray(app.img),'sobel');
                            imshow(app.img,'Parent',app.UIAxes_3);
                        else
                            app.img=edge(app.img_src,'sobel');
                            imshow(app.img,'Parent',app.UIAxes_3);
                        end
                    case "Roberts"
                        if ndims(app.img) == 3
                            app.img=edge(rgb2gray(app.img),'roberts');
                            imshow(app.img,'Parent',app.UIAxes_3);
                        else
                            app.img=edge(app.img_src,'roberts');
                            imshow(app.img,'Parent',app.UIAxes_3);
                        end
                    case "Dilation"
                        if islogical(app.img)
                            sel=ones(3);
                            app.img=imdilate(app.img_src,sel);
    
                        else
                            app.img=imbinarize(app.img);
                            sel=ones(3);
                            app.img=imdilate(app.img_src,sel);
                            
                        end
                        imshow(app.img,'Parent',app.UIAxes_3);
                    case "Erosion"
                        if islogical(app.img)
                            sel=ones(3);
                            app.img=imerode(app.img_src,sel);
    
                        else
                            app.img=imbinarize(app.img);
                            sel=ones(3);
                            app.img=imerode(app.img_src,sel);
                            
                        end
                        imshow(app.img,'Parent',app.UIAxes_3);
                end
            else 
                errordlg('Filter should be between 3 and 21','Field Error');
            end
        end

        % Value changing function: AdjustThresholdKnob
        function AdjustThresholdKnobValueChanging(app, event)
            changingValue = event.Value;
            

            value = app.OptionDropDown.Value;
            switch value
                case "Salt & Pepper"
                    app.img=imnoise(app.img_src,'salt & pepper',changingValue);
                    imshow(app.img,'Parent',app.UIAxes_2);
                case "Speckle"
                    app.img=imnoise(app.img_src,'speckle',changingValue);
                    imshow(app.img,'Parent',app.UIAxes_2);
                case "Gaussian"
                    app.img=imnoise(app.img_src,'gaussian',changingValue);
                    imshow(app.img,'Parent',app.UIAxes_2);
                                
                case "Median Filtering"
%                     app.img=imnoise(app.img,'salt & pepper',0.02);
%                     %app.img2=medfilt2(rgb2gray(app.img));
%                     app.img2=medfilt3(app.img);
%                     imshow(app.img2,'Parent',app.UIAxes_3);
                 
                    app.img=medfilt3(app.img);
                    imshow(app.img,'Parent',app.UIAxes_3);

                case "Average Filtering"
%                     %% NOT WORKING
                      %% NOT WORKING
                      %%imfilter instead of filter 2
                    ff=fspecial('average');
%                     f=filter2(ff,app.img,'valid');
                    f=imfilter(app.img,ff);
                    imshow(f,'Parent',app.UIAxes_3);
                case "Canny"
                    if ndims(app.img) == 3
                        app.img=edge(rgb2gray(app.img),'Canny');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    else
                        app.img=edge(app.img_src,'Canny');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    end

                case "Prewitt"
                    if ndims(app.img) == 3
                        app.img=edge(rgb2gray(app.img),'prewitt');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    else
                        app.img=edge(app.img_src,'prewitt');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    end
                case "Sobel"
                    if ndims(app.img) == 3
                        app.img=edge(rgb2gray(app.img),'sobel');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    else
                        app.img=edge(app.img_src,'sobel');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    end
                case "Roberts"
                    if ndims(app.img) == 3
                        app.img=edge(rgb2gray(app.img),'roberts');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    else
                        app.img=edge(app.img_src,'roberts');
                        imshow(app.img,'Parent',app.UIAxes_3);
                    end
                case "Dilation"
                    if islogical(app.img)
                        sel=ones(3);
                        app.img=imdilate(app.img_src,sel);

                    else
                        app.img=imbinarize(app.img);
                        sel=ones(3);
                        app.img=imdilate(app.img_src,sel);
                        
                    end
                    imshow(app.img,'Parent',app.UIAxes_3);
                case "Erosion"
                    if islogical(app.img)
                        sel=ones(3);
                        app.img=imerode(app.img_src,sel);

                    else
                        app.img=imbinarize(app.img);
                        sel=ones(3);
                        app.img=imerode(app.img_src,sel);
                        
                    end
                    imshow(app.img,'Parent',app.UIAxes_3);


%                case "Butterworth"
%                    app.img=rgb2gray(app.img);
%                    [x,y]= meshgrid(-128:127, -128:127);
%                    b1= 1./1+((x.^2+y.^2)/15).^2;
%                    cl=lbutter(b1,5,1);
%                    cf=fftshift(fft2(app.img));
%                    cfc1=cf.*b1;
%                    %fftshow(cfc1,'log');
%                    imshow(cfc1,'Parent',app.UIAxes_2);
%                    cfc1=cf.*cl;
%                    cfcli=ifft2(cfc1);
                    %fftshow(cfcli,'log');
%                    imshow(cfcli,'Parent',app.UIAxes_3);

            end
            
            
        end

        % Button pushed function: DownloadImageButton
        function DownloadImageButtonPushed(app, event)
            fig2Save = {'*.jpg';'*.png'};
            [filename, pathname] = uiputfile(fig2Save);
            newfilename = fullfile(pathname, filename);
            imwrite(app.img,newfilename)
            %saveas(fig2Save, newfilename);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'white');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            colormap(app.UIAxes, 'white')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Intermediate Image')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            colormap(app.UIAxes_2, 'white')
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Processed Image')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            colormap(app.UIAxes_3, 'white')
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Blossomorph'};
            app.DownloadImageButton = uibutton(app.UIFigure, 'push');
            app.DownloadImageButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadImageButtonPushed, true);
            app.DownloadImageButton.Text = 'Download Image';
            app.AdjustThresholdKnobLabel = uilabel(app.UIFigure);
            app.AdjustThresholdKnobLabel.Text = 'Adjust Threshold';
            app.AdjustThresholdKnob = uiknob(app.UIFigure, 'continuous');
            app.AdjustThresholdKnob.Limits = [0 1];
            app.AdjustThresholdKnob.MajorTicks = [0 0.25 0.5 0.75 1];
            app.AdjustThresholdKnob.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            app.AdjustThresholdKnob.ValueChangingFcn = createCallbackFcn(app, @AdjustThresholdKnobValueChanging, true);
            app.TechniqueDropDown = uidropdown(app.UIFigure);
            app.TechniqueDropDown.Items = {'Noise Addition', 'Noise Removal', 'Edge Detection', 'Morphological operation', 'Change color scheme', 'Other operations'};
            app.TechniqueDropDown.ValueChangedFcn = createCallbackFcn(app, @TechniqueDropDownChanged, true);
            app.TechniqueDropDown.Placeholder = 'Technique';
            app.TechniqueDropDown.ClickedFcn = createCallbackFcn(app, @TechniqueDropDownClicked, true);
            app.TechniqueDropDown.Value = 'Noise Addition';
            app.ApplyimageprocessingtechniqueLabel = uilabel(app.UIFigure);
            app.ApplyimageprocessingtechniqueLabel.Text = 'Apply image processing technique';
            app.OptionDropDown = uidropdown(app.UIFigure);
            app.OptionDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionDropDownValueChanged, true);
            app.OptionDropDown.Placeholder = 'Technique options';
            app.OptionDropDown.ClickedFcn = createCallbackFcn(app, @OptionDropDownClicked, true);
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Screenshot (116).png');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '23-234021_sakura-japan-flower-8pix-pixel-pink-aesthetic-kawaii.png');
            app.Specifyfiltersize321Label = uilabel(app.UIFigure);
            app.Specifyfiltersize321Label.Text = 'Specify filter size ( 3 - 21 )';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Limits = [0 21];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 3;
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Image Processing app'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DIP_PROJ_finalSUB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end